classdef EqualizerApp_ErbulatovH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        LoadButton     matlab.ui.control.Button
        PlayButton     matlab.ui.control.Button
        PauseButton    matlab.ui.control.Button
        StopButton     matlab.ui.control.Button
        HzSliderLabel  matlab.ui.control.Label
        gain1          matlab.ui.control.Slider
        HzLabel        matlab.ui.control.Label
        gain2          matlab.ui.control.Slider
        HzLabel_2      matlab.ui.control.Label
        gain3          matlab.ui.control.Slider
        HzLabel_3      matlab.ui.control.Label
        gain4          matlab.ui.control.Slider
        HzLabel_4      matlab.ui.control.Label
        gain5          matlab.ui.control.Slider
        HzLabel_5      matlab.ui.control.Label
        gain6          matlab.ui.control.Slider
        HzLabel_6      matlab.ui.control.Label
        gain7          matlab.ui.control.Slider
        HzLabel_7      matlab.ui.control.Label
        gain8          matlab.ui.control.Slider
        HzLabel_8      matlab.ui.control.Label
        gain9          matlab.ui.control.Slider
        HzLabel_9      matlab.ui.control.Label
        gain10         matlab.ui.control.Slider
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        Label          matlab.ui.control.Label
    end

    
    properties (Access = public)
        equalizer = Equalizer(64, 44100) % Description
        deviceWriter = audioDeviceWriter('SampleRate', 44100)
        fileReader = dsp.AudioFileReader
    end
    
    properties (Access = protected)
        filename % Description
        stopFlag = false
    end
    
    methods (Access = private)
        
        function PlotFreqResponse(app)
            [Hdb, w] = GetFreqResponse(app.equalizer)
            for i = 1:10
                plot(app.UIAxes2, w(i, :), Hdb(i, :))
            end
        end
    end


    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.Text = {'Load'; ''};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = {'31Hz'; ''};
            app.gain1 = uislider(app.UIFigure);
            app.gain1.Limits = [-10 10];
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = {'62Hz'; ''};
            app.gain2 = uislider(app.UIFigure);
            app.gain2.Limits = [-10 10];
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = {'125Hz'; ''};
            app.gain3 = uislider(app.UIFigure);
            app.gain3.Limits = [-10 10];
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = {'250Hz'; ''};
            app.gain4 = uislider(app.UIFigure);
            app.gain4.Limits = [-10 10];
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = {'500Hz'; ''};
            app.gain5 = uislider(app.UIFigure);
            app.gain5.Limits = [-10 10];
            app.HzLabel_5 = uilabel(app.UIFigure);
            app.HzLabel_5.Text = {'1000Hz'; ''};
            app.gain6 = uislider(app.UIFigure);
            app.gain6.Limits = [-10 10];
            app.HzLabel_6 = uilabel(app.UIFigure);
            app.HzLabel_6.Text = {'2000Hz'; ''};
            app.gain7 = uislider(app.UIFigure);
            app.gain7.Limits = [-10 10];
            app.HzLabel_7 = uilabel(app.UIFigure);
            app.HzLabel_7.Text = {'4000Hz'; ''};
            app.gain8 = uislider(app.UIFigure);
            app.gain8.Limits = [-10 10];
            app.HzLabel_8 = uilabel(app.UIFigure);
            app.HzLabel_8.Text = {'8000Hz'; ''};
            app.gain9 = uislider(app.UIFigure);
            app.gain9.Limits = [-10 10];
            app.HzLabel_9 = uilabel(app.UIFigure);
            app.HzLabel_9.Text = {'16000Hz'; ''};
            app.gain10 = uislider(app.UIFigure);
            app.gain10.Limits = [-10 10];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FreqResponse')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Label = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = EqualizerApp_ErbulatovH

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end